classdef defineCellROIs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MenuUIFigure                  matlab.ui.Figure
        cellsSlider                   matlab.ui.control.Slider
        cellsSliderLabel              matlab.ui.control.Label
        ImageLabel                    matlab.ui.control.Label
        projectionSlider              matlab.ui.control.Slider
        projectionSliderLabel         matlab.ui.control.Label
        RefineCellROIsLabel           matlab.ui.control.Label
        AutomatedCellDetectionLabel   matlab.ui.control.Label
        activitySlider                matlab.ui.control.Slider
        activitySliderLabel           matlab.ui.control.Label
        ZoomoutButton                 matlab.ui.control.Button
        resetButton                   matlab.ui.control.Button
        refineROIsButton              matlab.ui.control.StateButton
        ZoominButton                  matlab.ui.control.StateButton
        savecellsButton               matlab.ui.control.Button
        diskRadiusEditField           matlab.ui.control.NumericEditField
        diskRadiusEditFieldLabel      matlab.ui.control.Label
        activitythresholdEditField    matlab.ui.control.NumericEditField
        activitythresholdEditFieldLabel  matlab.ui.control.Label
        HmaximaEditField              matlab.ui.control.NumericEditField
        HmaximaEditFieldLabel         matlab.ui.control.Label
        maxPixelsEditField            matlab.ui.control.NumericEditField
        maxPixelsEditFieldLabel       matlab.ui.control.Label
        minPixelsEditField            matlab.ui.control.NumericEditField
        minPixelsEditFieldLabel       matlab.ui.control.Label
        windowSizeEditField           matlab.ui.control.NumericEditField
        windowSizeEditFieldLabel      matlab.ui.control.Label
        activityoffsetEditField       matlab.ui.control.NumericEditField
        activityoffsetEditFieldLabel  matlab.ui.control.Label
        detectcellsButton             matlab.ui.control.Button
    end


    properties (Access = private)
        
        % Root dir
        rootDir = [];
        
        % Mouse data
        mouseName = [];
        dates = [];
        blocks = [];
        
        % Figure
        fig = [];
        ax = [];
        I = [];
        map = [];
        
        % Registration
        registeredProjections = [];
        registeredActivityMaps = [];
        rowShiftVector = [];
        columnShiftVector = [];
        
        % Cell detection
        activityMapThreshold = 0;       % threshold for activity map
        offset = -0.05;                 % offset for actuivity map
        diskRadius = 5;                 % radius for morphologial opening of activity map
        windowSize = 100;               % window size for filtering activity map
        minPixels = 200;                % minimum number of pixels for ROI size
        maxPixels = 400;                % maximum number of pixels for ROI size
        Hmaxima = 0;                    % threshold for extended-maxima transform
        
        % Cells
        averageActivityMap = [];
        ROIMap = [];
        cellROIs = [];
        reverseCellROIs = [];
        projectionIntensity = 35;
        
        % Freehand ROI
        h = [];
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mouseName, dates, blocks)
            
            % Root Dir
            app.rootDir = pwd;
            
            % Mouse
            app.mouseName = mouseName;
        
            % Display
            fprintf('\n')
            disp('     Loading data...')
            
            % Registration across sessions
            [app.registeredProjections,app.registeredActivityMaps,app.rowShiftVector,app.columnShiftVector,app.dates,app.blocks] = registerSessions(mouseName,dates,blocks,app.rootDir);
            
            % Update image
            app.fig = uifigure('Name','Image');
            set(app.fig,'Position',[150 75 1000 950])
            app.ax = axes(app.fig);
            app.map = zeros(size(app.registeredProjections,1),size(app.registeredProjections,2),3);
            app.map(:,:,1) = mean(app.registeredProjections,3);
            app.map(:,:,2) = mean(app.registeredActivityMaps,3);
            app.map(:,:,3) = mean(app.registeredProjections,3);
            app.I = imagesc(app.ax,app.map);
            set(app.ax,'YDir','reverse'), axis(app.ax,'off')
            
            % Reduce projection intensity
            app.projectionSlider.Value = app.projectionIntensity;
            newMap = app.map;
            newMap(:,:,1) = (app.projectionIntensity/100)*app.map(:,:,1);
            newMap(:,:,3) = (app.projectionIntensity/100)*app.map(:,:,3);
            app.I = imagesc(app.ax,newMap);

            fprintf('\n')
            
        end

        % Value changed function: activitythresholdEditField
        function activitythresholdEditFieldValueChanged(app, event)
            app.activityMapThreshold = app.activitythresholdEditField.Value;
        end

        % Value changed function: activityoffsetEditField
        function activityoffsetEditFieldValueChanged(app, event)
            app.offset = app.activityoffsetEditField.Value;
        end

        % Value changed function: diskRadiusEditField
        function diskRadiusEditFieldValueChanged(app, event)
            app.diskRadius = app.diskRadiusEditField.Value;
        end

        % Value changed function: windowSizeEditField
        function windowSizeEditFieldValueChanged(app, event)
            app.windowSize = app.windowSizeEditField.Value;
        end

        % Value changed function: minPixelsEditField
        function minPixelsEditFieldValueChanged(app, event)
            app.minPixels = app.minPixelsEditField.Value;
        end

        % Value changed function: maxPixelsEditField
        function maxPixelsEditFieldValueChanged(app, event)
            app.maxPixels = app.maxPixelsEditField.Value;
        end

        % Value changed function: HmaximaEditField
        function HmaximaEditFieldValueChanged(app, event)
            app.Hmaxima = app.HmaximaEditField.Value;
        end

        % Button pushed function: detectcellsButton
        function detectcellsButtonPushed(app, event)

            % Look for previously defined ROIs
            
                % Since all average projections are aligned to the one corresponding to the first experiment
                if exist([app.rootDir,'\exampleExperiment\',app.mouseName,'_',num2str(app.dates(1)),'_',num2str(app.blocks(1)),'.mat'],'file') > 0
                    load([app.rootDir,'\exampleExperiment\',app.mouseName,'_',num2str(app.dates(1)),'_',num2str(app.blocks(1)),'.mat'])
                    
                    % Check for existing ROIs
                    if isfield(mouseData,'cellROIs')
                        answer = questdlg('Would you like to load existing ROIs?','Load ROIs','Yes','No','Yes');
                        switch answer
                            case 'Yes'
                                app.cellROIs = mouseData.cellROIs;
                                app.ROIMap = zeros(size(mouseData.activityMap,1),size(mouseData.activityMap,2),'single');
                                for i=1:size(app.cellROIs,1)
                                    app.ROIMap = app.ROIMap + single(poly2mask(app.cellROIs{i}(:,1),app.cellROIs{i}(:,2),size(mouseData.activityMap,1),size(mouseData.activityMap,2)));
                                end
                        end
                    end
                    clear('mouseData')
                    
                end
                
            if ~exist('answer','var') || strcmp(answer,'No')
                    % Automated cell detection
                    [app.averageActivityMap,app.ROIMap,app.cellROIs] = detectCells(app.registeredActivityMaps,app.rowShiftVector,app.columnShiftVector,app.activityMapThreshold,app.offset,app.diskRadius,app.windowSize,app.minPixels,app.maxPixels,app.Hmaxima);
            elseif strcmp(answer,'Yes')
                    % Just get the activity map
                    [app.averageActivityMap] = detectCells(app.registeredActivityMaps,app.rowShiftVector,app.columnShiftVector,app.activityMapThreshold,app.offset,app.diskRadius,app.windowSize,app.minPixels,app.maxPixels,app.Hmaxima);
            end
            
            % Update image
            app.map = zeros(size(app.averageActivityMap,1),size(app.averageActivityMap,2),3);
            app.map(:,:,1) = app.ROIMap;
            app.map(:,:,2) = app.averageActivityMap;
            app.map(:,:,3) = mean(app.registeredProjections,3);
            app.I = imagesc(app.ax,app.map);
            set(app.ax,'YDir','reverse'), axis(app.ax,'off')
            
            % Reduce projection intensity
            app.projectionSlider.Value = app.projectionIntensity;
            newMap = app.map;
            newMap(:,:,3) = (app.projectionIntensity/100)*app.map(:,:,3);
            app.I = imagesc(app.ax,newMap);
            
            % Reset buttons
            app.refineROIsButton.Value = 0;
            app.refineROIsButton.BackgroundColor = [0.96 0.96 0.96];
            app.refineROIsButton.FontColor = [0 0 0];
            app.ZoominButton.Value = 0;
            app.ZoominButton.BackgroundColor = [0.96 0.96 0.96];
            app.ZoominButton.FontColor = [0 0 0];
            zoom(app.ax,'off')
            
            
        end

        % Button pushed function: savecellsButton
        function savecellsButtonPushed(app, event)
            
            if ~isempty(app.cellROIs)
                
                answer = questdlg('Would you like to save the current ROIs?','Save','Yes','No','Yes');
                
                switch answer
                    
                    case 'Yes'

                        % Display
                        disp('     Reversing cell ROI coordinates...')
                        wb = waitbar(0,'Checking for error ROIs...','Name','Reversing cell ROI coordinates...','CreateCancelBtn','setappdata(gcbf,''canceling'',1)');
                        setappdata(wb,'canceling',0);
                        
                        % Delete error ROIs
                        for i=1:size(app.cellROIs,1)
                            if size(app.cellROIs,1) >= i
                                ROI = poly2mask(app.cellROIs{i}(:,1),app.cellROIs{i}(:,2),size(app.averageActivityMap,1),size(app.averageActivityMap,2));
                                CC = bwconncomp(ROI,4);
                                if CC.NumObjects == 0
                                    app.cellROIs(i) = [];
                                end
                                clear('ROI','CC')
                            end
                        end
                        waitbar(0,wb,'Reversing cell ROI coordinates...')
                        
                        % Reverse registration of cell ROIs
                        app.reverseCellROIs = cell(size(app.registeredActivityMaps,3),1);
                        for j=1:size(app.registeredActivityMaps,3)
                            % Waitbar
                                % Check for clicked Cancel button
                                if getappdata(wb,'canceling')
                                    selection = questdlg('Do you want to stop this process?','Stop process','Yes','No','Yes');
                                        switch selection
                                            case 'Yes'
                                                delete(gcf)
                                                break
                                            case 'No'
                                                setappdata(wb,'canceling',0);
                                        end
                                end
                                % Update waitbar and message
                                waitbar(j/size(app.registeredActivityMaps,3),wb,['stack ',num2str(j),' out of ',num2str(size(app.registeredActivityMaps,3))])
                            stackCellROIs = app.cellROIs;
                            for i=1:size(app.cellROIs,1)
                                stackCellROIs{i}(:,1) = app.cellROIs{i}(:,1) - app.columnShiftVector(j);
                                stackCellROIs{i}(:,2) = app.cellROIs{i}(:,2) - app.rowShiftVector(j);
                            end
                            ROIs = zeros(size(app.registeredActivityMaps,1),size(app.registeredActivityMaps,2),'single');
                            for i=1:size(stackCellROIs,1)
                                ROIs = ROIs + single(poly2mask(stackCellROIs{i}(:,1),stackCellROIs{i}(:,2),size(app.registeredActivityMaps,1),size(app.registeredActivityMaps,2)));
                            end
                            app.reverseCellROIs{j} = stackCellROIs;
                            clear('stackCellROIs')
                        end
                        delete(wb)
                        
                        % Display
                        disp('     Saving cell ROIs...')
                        wb = waitbar(0,'Saving cell ROIs...','Name','Saving cell ROIs...','CreateCancelBtn','setappdata(gcbf,''canceling'',1)');
                        setappdata(wb,'canceling',0);
                        
                        % Save cell ROIs
                        for i=1:size(app.registeredActivityMaps,3)
                            % Waitbar
                                % Check for clicked Cancel button
                                if getappdata(wb,'canceling')
                                    selection = questdlg('Do you want to stop this process?','Stop process','Yes','No','Yes');
                                        switch selection
                                            case 'Yes'
                                                delete(gcf)
                                                break
                                            case 'No'
                                                setappdata(wb,'canceling',0);
                                        end
                                end
                                % Update waitbar and message
                                waitbar(i/size(app.registeredActivityMaps,3),wb,['stack ',num2str(i),' out of ',num2str(size(app.registeredActivityMaps,3))])
                            % Load and save
                            load([app.rootDir,'\exampleExperiment\',app.mouseName,'_',num2str(app.dates(i)),'_',num2str(app.blocks(i)),'.mat'])
                            mouseData.cellROIs = app.reverseCellROIs{i};
                            save([app.rootDir,'\exampleExperiment\',app.mouseName,'_',num2str(app.dates(i)),'_',num2str(app.blocks(i)),'.mat'],'mouseData')
                        end
                        delete(wb)
                        
                        % Display
                        disp('     Cell ROIs have been saved.')
                        delete(app.fig)
                        delete(app)
                        fprintf('\n')
        
                    case 'No'
                        
                        % Continue working on cell RoIs...
                        
                end
                
            else

                % Display
                d = dialog('Position',[500 500 300 120],'Name','Warning');
                uicontrol('Parent',d,'Style','text','Position',[50 70 200 40],'String','No cell ROIs have been defined.');
                uicontrol('Parent',d,'Position',[100 25 100 35],'String','Close','Callback','delete(gcf)');
                clear('d')
                
            end
            
        end

        % Value changed function: ZoominButton
        function ZoominButtonValueChanged(app, event)
        
            % Zoom
            if app.ZoominButton.Value == 1 && app.refineROIsButton.Value == 0
                % Zoom in on
                zoom(app.ax,'on')
                app.ZoominButton.BackgroundColor = [0 0.8 0];
                app.ZoominButton.FontColor = [0 0.2 0];
            elseif app.ZoominButton.Value == 1 && app.refineROIsButton.Value == 1
                    % Display
                    d = dialog('Position',[500 500 300 120],'Name','Warning');
                    uicontrol('Parent',d,'Style','text','Position',[50 70 200 40],'String','Finish drawing your ROI first.');
                    uicontrol('Parent',d,'Position',[100 25 100 35],'String','Close','Callback','delete(gcf)');
                    clear('d')
                    % Turn button off
                    app.ZoominButton.Value = 0;
                    app.ZoominButton.BackgroundColor = [0.96 0.96 0.96];
                    app.ZoominButton.FontColor = [0 0 0];
            elseif app.ZoominButton.Value == 0
                % Zoom in off
                zoom(app.ax,'off')
                app.ZoominButton.BackgroundColor = [0.96 0.96 0.96];
                app.ZoominButton.FontColor = [0 0 0];
            end


        end

        % Value changed function: refineROIsButton
        function refineROIsButtonValueChanged(app, event)
            
            if app.refineROIsButton.Value == 1 && ~isempty(app.cellROIs)
                
                app.refineROIsButton.BackgroundColor = [1 0.1 0];
                app.refineROIsButton.FontColor = [0.2 0 0];

                % Turn zoom in off
                zoom(app.ax,'off')
                app.ZoominButton.Value = 0;
                app.ZoominButton.BackgroundColor = [0.96 0.96 0.96];
                app.ZoominButton.FontColor = [0 0 0];
                
                % Define ROI
                app.h = drawfreehand(app.ax,'Color',[1 0 0]);                
                
                if ~isempty(app.h.Position)
                
                    newROI = poly2mask(app.h.Position(:,1),app.h.Position(:,2),size(app.averageActivityMap,1),size(app.averageActivityMap,2));
    
                    % Check for ROI existance (to prevent misclick errors)
                    CC = bwconncomp(newROI,4);
                    
                    if CC.NumObjects ~= 0
                    
                        % Check for complete encompassing of ROI
                        in = [];
                        for j = 1:length(app.cellROIs)
                            if min(newROI(poly2mask(app.cellROIs{j}(:,1),app.cellROIs{j}(:,2),size(app.averageActivityMap,1),size(app.averageActivityMap,2)))) == 1
                                in = cat(1,in,j);
                            end
                        end
                        if isempty(in)
                            % Update cell matrix
                            app.cellROIs{length(app.cellROIs)+1} = app.h.Position;
                        else
                            % Delete cell(s)
                            app.cellROIs(in) = [];
                            app.cellROIs = squeeze(app.cellROIs);
                        end
                        
                        % Update image
                        app.ROIMap = zeros(size(app.averageActivityMap,1),size(app.averageActivityMap,2),'single');
                        for i=1:size(app.cellROIs,1)
                            app.ROIMap = app.ROIMap + single(poly2mask(app.cellROIs{i}(:,1),app.cellROIs{i}(:,2),size(app.averageActivityMap,1),size(app.averageActivityMap,2)));
                        end
                        app.map = zeros(size(app.averageActivityMap,1),size(app.averageActivityMap,2),3);
                        app.map(:,:,1) = app.ROIMap;
                        app.map(:,:,2) = app.averageActivityMap;
                        app.map(:,:,3) = mean(app.registeredProjections,3);
                        
                        newMap = app.map;
                        
                        % Reduce projection intensity
                        app.projectionSlider.Value = app.projectionIntensity;
                        newMap(:,:,3) = (app.projectionIntensity/100)*app.map(:,:,3);
                        
                        % Keep current intensity for activity map and average projection
                        x1 = app.activitySlider.Value;
                        x2 = app.cellsSlider.Value;
                        newMap(:,:,2) = (x1/100)*app.map(:,:,2);
                        newMap(:,:,1) = (x2/100)*app.map(:,:,1);
                        
                        % Display image
                        xLimits = get(app.ax,'XLim');
                        yLimits = get(app.ax,'YLim');
                        app.I = imagesc(app.ax,newMap);
                        set(app.ax,'YDir','reverse'), axis(app.ax,'off')
                        xlim(app.ax,xLimits)
                        ylim(app.ax,yLimits)
                        
                    else
                        
                        % Do nothing...
                    
                    end
                    
                    % Turn refine ROI off
                    app.refineROIsButton.Value = 0;
                    app.refineROIsButton.BackgroundColor = [0.96 0.96 0.96];
                    app.refineROIsButton.FontColor = [0 0 0];
                    
                end
                
            elseif isempty(app.cellROIs)
                
                % Turn refine ROI off
                app.refineROIsButton.Value = 0;
                app.refineROIsButton.BackgroundColor = [0.96 0.96 0.96];
                app.refineROIsButton.FontColor = [0 0 0];
                
                % Display
                d = dialog('Position',[500 500 300 120],'Name','Warning');
                uicontrol('Parent',d,'Style','text','Position',[50 70 200 40],'String','Please detect cells first.');
                uicontrol('Parent',d,'Position',[100 25 100 35],'String','Close','Callback','delete(gcf)');
                clear('d')

            end
            
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            
            if isempty(app.cellROIs)
                % Display
                d = dialog('Position',[500 500 300 120],'Name','Warning');
                uicontrol('Parent',d,'Style','text','Position',[50 70 200 40],'String','No cell ROIs have been defined.');
                uicontrol('Parent',d,'Position',[100 25 100 35],'String','Close','Callback','delete(gcf)');
                clear('d')
                answer = 'No';
            elseif ~isempty(app.cellROIs)
                answer = questdlg('Do you want to reset cell ROI detection?','Reset','Yes','No','No');
            end
                
            switch answer
                
                case 'Yes'
        
                    % Update image
                    app.map = zeros(size(app.registeredProjections,1),size(app.registeredProjections,2),3);
                    app.map(:,:,1) = mean(app.registeredProjections,3);
                    app.map(:,:,2) = mean(app.registeredActivityMaps,3);
                    app.map(:,:,3) = mean(app.registeredProjections,3);
                    app.I = imagesc(app.ax,app.map);
                    set(app.ax,'YDir','reverse'), axis(app.ax,'off')
                    
                    % Reduce projection intensity
                    app.projectionSlider.Value = app.projectionIntensity;
                    newMap = app.map;
                    newMap(:,:,1) = (app.projectionIntensity/100)*app.map(:,:,1);
                    newMap(:,:,3) = (app.projectionIntensity/100)*app.map(:,:,3);
                    app.I = imagesc(app.ax,newMap);
                    
                    % Reset ROIs
                    app.cellROIs = [];
                    
                    % Reset buttons
                    app.refineROIsButton.Value = 0;
                    app.refineROIsButton.BackgroundColor = [0.96 0.96 0.96];
                    app.refineROIsButton.FontColor = [0 0 0];
                    app.ZoominButton.Value = 0;
                    app.ZoominButton.BackgroundColor = [0.96 0.96 0.96];
                    app.ZoominButton.FontColor = [0 0 0];
            
                case 'No'
                    
            end
        
        end

        % Button pushed function: ZoomoutButton
        function ZoomoutButtonPushed(app, event)

            % Zoom out
            app.ax.XLim = 0.5 + [0 size(app.registeredProjections,1)];
            app.ax.YLim = 0.5 + [0 size(app.registeredProjections,2)];
            
            % Turn zoom in off
            zoom(app.ax,'off')
            app.ZoominButton.Value = 0;
            app.ZoominButton.BackgroundColor = [0.96 0.96 0.96];
            app.ZoominButton.FontColor = [0 0 0];
        
        end

        % Value changing function: activitySlider
        function activitySliderValueChanging(app, event)

            app.ZoominButton.Value = 0;
            zoom(app.ax,'off')
            
            newMap = app.map;
            x1 = app.projectionSlider.Value;
            x2 = app.cellsSlider.Value;
            
            % Activity
            newMap(:,:,2) = (event.Value/100)*app.map(:,:,2);

            if isempty(app.cellROIs)
                % Projection
                newMap(:,:,1) = (x1/100)*app.map(:,:,1);
                newMap(:,:,3) = (x1/100)*app.map(:,:,3);
            else
                % Projection
                newMap(:,:,3) = (x1/100)*app.map(:,:,3);
                % Cells
                newMap(:,:,1) = (x2/100)*app.map(:,:,1);
            end
            
            % Update image
            xLimits = get(app.ax,'XLim');
            yLimits = get(app.ax,'YLim');
            app.I = imagesc(app.ax,newMap);
            xlim(app.ax,xLimits)
            ylim(app.ax,yLimits)
            
        end

        % Value changing function: projectionSlider
        function projectionSliderValueChanging(app, event)

            app.ZoominButton.Value = 0;
            zoom(app.ax,'off')
        
            newMap = app.map;
            x1 = app.activitySlider.Value;
            x2 = app.cellsSlider.Value;
            
            if isempty(app.cellROIs)
                % Projection
                newMap(:,:,1) = (event.Value/100)*app.map(:,:,1);
                newMap(:,:,3) = (event.Value/100)*app.map(:,:,3);
                % Activity
                newMap(:,:,2) = (x1/100)*app.map(:,:,2);
            else
                % Projection
                newMap(:,:,3) = (event.Value/100)*app.map(:,:,3);
                % Activity
                newMap(:,:,2) = (x1/100)*app.map(:,:,2);
                % Cells
                newMap(:,:,1) = (x2/100)*app.map(:,:,1);
            end
            
            % Update image
            xLimits = get(app.ax,'XLim');
            yLimits = get(app.ax,'YLim');
            app.I = imagesc(app.ax,newMap);
            xlim(app.ax,xLimits)
            ylim(app.ax,yLimits)
            
        end

        % Value changing function: cellsSlider
        function cellsSliderValueChanging(app, event)

            if ~isempty(app.cellROIs)
                
                app.ZoominButton.Value = 0;
                zoom(app.ax,'off')
                
                newMap = app.map;
                
                % Cells
                newMap(:,:,1) = (event.Value/100)*app.map(:,:,1);
                
                % Adjust projection
                x = app.projectionSlider.Value;
                newMap(:,:,3) = (x/100)*app.map(:,:,3);
                
                % Update image
                xLimits = get(app.ax,'XLim');
                yLimits = get(app.ax,'YLim');
                app.I = imagesc(app.ax,newMap);
                xlim(app.ax,xLimits)
                ylim(app.ax,yLimits)
                
            end
            
        end

        % Close request function: MenuUIFigure
        function MenuUIFigureCloseRequest(app, event)
            
            delete(app.fig)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MenuUIFigure = uifigure('Visible', 'off');
            app.MenuUIFigure.Name = 'Menu';
            app.MenuUIFigure.CloseRequestFcn = createCallbackFcn(app, @MenuUIFigureCloseRequest, true);
            app.detectcellsButton = uibutton(app.MenuUIFigure, 'push');
            app.detectcellsButton.ButtonPushedFcn = createCallbackFcn(app, @detectcellsButtonPushed, true);
            app.detectcellsButton.Text = 'detect cells';
            app.activityoffsetEditFieldLabel = uilabel(app.MenuUIFigure);
            app.activityoffsetEditFieldLabel.Text = 'activity offset';
            app.activityoffsetEditField = uieditfield(app.MenuUIFigure, 'numeric');
            app.activityoffsetEditField.ValueChangedFcn = createCallbackFcn(app, @activityoffsetEditFieldValueChanged, true);
            app.activityoffsetEditField.Value = -0.05;
            app.windowSizeEditFieldLabel = uilabel(app.MenuUIFigure);
            app.windowSizeEditFieldLabel.Text = 'windowSize';
            app.windowSizeEditField = uieditfield(app.MenuUIFigure, 'numeric');
            app.windowSizeEditField.ValueChangedFcn = createCallbackFcn(app, @windowSizeEditFieldValueChanged, true);
            app.windowSizeEditField.Value = 100;
            app.minPixelsEditFieldLabel = uilabel(app.MenuUIFigure);
            app.minPixelsEditFieldLabel.Text = 'minPixels';
            app.minPixelsEditField = uieditfield(app.MenuUIFigure, 'numeric');
            app.minPixelsEditField.ValueChangedFcn = createCallbackFcn(app, @minPixelsEditFieldValueChanged, true);
            app.minPixelsEditField.Value = 200;
            app.maxPixelsEditFieldLabel = uilabel(app.MenuUIFigure);
            app.maxPixelsEditFieldLabel.Text = 'maxPixels';
            app.maxPixelsEditField = uieditfield(app.MenuUIFigure, 'numeric');
            app.maxPixelsEditField.ValueChangedFcn = createCallbackFcn(app, @maxPixelsEditFieldValueChanged, true);
            app.maxPixelsEditField.Value = 400;
            app.HmaximaEditFieldLabel = uilabel(app.MenuUIFigure);
            app.HmaximaEditFieldLabel.Text = 'Hmaxima';
            app.HmaximaEditField = uieditfield(app.MenuUIFigure, 'numeric');
            app.HmaximaEditField.ValueChangedFcn = createCallbackFcn(app, @HmaximaEditFieldValueChanged, true);
            app.activitythresholdEditFieldLabel = uilabel(app.MenuUIFigure);
            app.activitythresholdEditFieldLabel.Text = 'activity threshold';
            app.activitythresholdEditField = uieditfield(app.MenuUIFigure, 'numeric');
            app.activitythresholdEditField.ValueChangedFcn = createCallbackFcn(app, @activitythresholdEditFieldValueChanged, true);
            app.diskRadiusEditFieldLabel = uilabel(app.MenuUIFigure);
            app.diskRadiusEditFieldLabel.Text = 'diskRadius';
            app.diskRadiusEditField = uieditfield(app.MenuUIFigure, 'numeric');
            app.diskRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @diskRadiusEditFieldValueChanged, true);
            app.diskRadiusEditField.Value = 5;
            app.savecellsButton = uibutton(app.MenuUIFigure, 'push');
            app.savecellsButton.ButtonPushedFcn = createCallbackFcn(app, @savecellsButtonPushed, true);
            app.savecellsButton.Text = 'save cells';
            app.ZoominButton = uibutton(app.MenuUIFigure, 'state');
            app.ZoominButton.ValueChangedFcn = createCallbackFcn(app, @ZoominButtonValueChanged, true);
            app.ZoominButton.Text = 'Zoom in';
            app.refineROIsButton = uibutton(app.MenuUIFigure, 'state');
            app.refineROIsButton.ValueChangedFcn = createCallbackFcn(app, @refineROIsButtonValueChanged, true);
            app.refineROIsButton.Text = 'refine ROIs';
            app.resetButton = uibutton(app.MenuUIFigure, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Text = 'reset';
            app.ZoomoutButton = uibutton(app.MenuUIFigure, 'push');
            app.ZoomoutButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomoutButtonPushed, true);
            app.ZoomoutButton.Text = 'Zoom out';
            app.activitySliderLabel = uilabel(app.MenuUIFigure);
            app.activitySliderLabel.Text = 'activity';
            app.activitySlider = uislider(app.MenuUIFigure);
            app.activitySlider.ValueChangingFcn = createCallbackFcn(app, @activitySliderValueChanging, true);
            app.activitySlider.Value = 100;
            app.AutomatedCellDetectionLabel = uilabel(app.MenuUIFigure);
            app.AutomatedCellDetectionLabel.Text = 'Automated Cell Detection';
            app.RefineCellROIsLabel = uilabel(app.MenuUIFigure);
            app.RefineCellROIsLabel.Text = 'Refine Cell ROIs';
            app.projectionSliderLabel = uilabel(app.MenuUIFigure);
            app.projectionSliderLabel.Text = 'projection';
            app.projectionSlider = uislider(app.MenuUIFigure);
            app.projectionSlider.ValueChangingFcn = createCallbackFcn(app, @projectionSliderValueChanging, true);
            app.projectionSlider.Value = 100;
            app.ImageLabel = uilabel(app.MenuUIFigure);
            app.ImageLabel.Text = 'Image';
            app.cellsSliderLabel = uilabel(app.MenuUIFigure);
            app.cellsSliderLabel.Text = 'cells';
            app.cellsSlider = uislider(app.MenuUIFigure);
            app.cellsSlider.ValueChangingFcn = createCallbackFcn(app, @cellsSliderValueChanging, true);
            app.cellsSlider.Value = 100;
            % Show the figure after all components are created
            app.MenuUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = defineCellROIs(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MenuUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MenuUIFigure)
        end
    end
end